classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        Button_5     matlab.ui.control.Button
        Lamp_2       matlab.ui.control.Lamp
        Button_4     matlab.ui.control.Button
        Lamp         matlab.ui.control.Lamp
        Label_12     matlab.ui.control.Label
        TextArea_5   matlab.ui.control.TextArea
        DropDown_3   matlab.ui.control.DropDown
        Label_11     matlab.ui.control.Label
        Label_10     matlab.ui.control.Label
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button_2     matlab.ui.control.RadioButton
        Button_3     matlab.ui.control.RadioButton
        Button       matlab.ui.control.RadioButton
        Label_9      matlab.ui.control.Label
        Label_8      matlab.ui.control.Label
        DropDown_2   matlab.ui.control.DropDown
        Label_7      matlab.ui.control.Label
        TextArea_4   matlab.ui.control.TextArea
        Label_6      matlab.ui.control.Label
        TextArea_3   matlab.ui.control.TextArea
        Label_5      matlab.ui.control.Label
        TextArea_2   matlab.ui.control.TextArea
        CheckBox     matlab.ui.control.CheckBox
        Label_4      matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        DropDown     matlab.ui.control.DropDown
        Label        matlab.ui.control.Label
        TextArea     matlab.ui.control.TextArea
        CenterPanel  matlab.ui.container.Panel
        Panel        matlab.ui.container.Panel
        GridLayout3  matlab.ui.container.GridLayout
        TextArea_7   matlab.ui.control.TextArea
        RightPanel   matlab.ui.container.Panel
        Lamp_7       matlab.ui.control.Lamp
        Label_16     matlab.ui.control.Label
        CheckBox_3   matlab.ui.control.CheckBox
        Button_10    matlab.ui.control.Button
        Lamp_6       matlab.ui.control.Lamp
        Button_9     matlab.ui.control.Button
        TextArea_6   matlab.ui.control.TextArea
        Label_15     matlab.ui.control.Label
        Lamp_5       matlab.ui.control.Lamp
        Label_14     matlab.ui.control.Label
        CheckBox_2   matlab.ui.control.CheckBox
        Lamp_4       matlab.ui.control.Lamp
        Button_8     matlab.ui.control.Button
        Button_6     matlab.ui.control.Button
        DropDown_4   matlab.ui.control.DropDown
        Label_13     matlab.ui.control.Label
        Lamp_3       matlab.ui.control.Lamp
        UITable      matlab.ui.control.Table
        Button_7     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Carriers = cell(100,5); % Description
        Num_of_Agent % Description
        Flags_of_Input = NaN(1,100); % Description
        Sensor_Info_One_Agent = [];% Description
        P % Description
        P_All % Description
        X % Description
        Names % Description
        S_Final % Description
    end
    
    properties (Access = public)
    end
    
    methods (Access = public)
        
        function results = getPrivateProperty(app)
            results = app.Carriers;
        end
    end
    
    methods (Access = private)
        
        function results = getS_Final(app)
            results = app.S_Final;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TextArea
        function Get_Num_of_Agent(app, event)
            temp = round(str2double(app.TextArea.Value));
            app.Num_of_Agent = temp;
            app.DropDown.Items = string(1:temp);  % 创建1到numAddends的选项
            %app.DropDown.Value = '1';  % 默认选择第一个
            % 清空所有数据
            app.Carriers = cell(app.Num_of_Agent,5);
            app.Flags_of_Input = NaN(1,app.Num_of_Agent);
            app.Sensor_Info_One_Agent = [];
            app.Lamp.Color = 'red';
            app.Lamp_2.Color = 'red';
            app.Lamp_3.Color = 'red';
            app.Lamp_4.Color = 'red';
            app.Lamp_5.Color = 'red';
            app.TextArea_2.Value = '';
            app.TextArea_3.Value = '';
            app.TextArea_4.Value = '';
            app.TextArea_5.Value = '';
            app.TextArea_6.Enable = "off";
            app.Button_9.Enable = "off";
            app.Button_10.Enable = "off";
            if isempty(gcp('nocreate'))
                app.Lamp_7.Color = 'red';
                app.CheckBox_3.Value = 0;
            else
                app.Lamp_7.Color = 'green';
                app.CheckBox_3.Value = 1;
            end
            
            matrix = NaN(temp, temp);
            colNames = arrayfun(@(x) sprintf('%d', x), 1:temp, 'UniformOutput', false);
            rowNames = arrayfun(@(x) sprintf('智能体%d', x), 1:temp, 'UniformOutput', false);
            app.UITable.ColumnName = colNames;
            app.UITable.ColumnWidth = repmat({40}, 1, temp);
            app.UITable.RowName = rowNames;
            app.UITable.Data = matrix;
            app.UITable.ColumnEditable = true;

            agentNames = cell(1, temp);
            for i = 1:temp
                agentNames{i} = sprintf('智能体%d导航性能P', i);
            end

            app.X = NaN(temp,temp);
            app.P = NaN(temp,temp);
            app.P_All = cell(1,temp);
            for i = 1:temp
                app.P_All{1,i} = NaN(temp,temp);
            end
            N = {'整体导航性能P', '状态估计分布X'};
            app.DropDown_4.Items = [N,agentNames];
            app.Names = [N,agentNames];
            app.DropDown_4.Value = '整体导航性能P';
%             app.DropDown.Value = 'None';
%             app.DropDown_2.Value = '';
%             app.DropDown_3.Value = '';
%             app.ButtonGroup.SelectedObject = [];
            %app.TextArea_6.Value = '';
        end

        % Value changed function: DropDown
        function Get_Current_Number_of_Agent(app, event)
            Current_Number = round(str2double(app.DropDown.Value));  % 获取当前选中的下拉选项
            %app.TextArea_6.Value = app.DropDown.Value;
            if isnan(app.Flags_of_Input(Current_Number))
                % 如果没有输入过该智能体的信息，则清空接下来所有格子
                app.Lamp.Color = 'red';
                app.Lamp_2.Color = 'red';
                app.TextArea_2.Value = '';
                app.TextArea_3.Value = '';
                app.TextArea_4.Value = '';
                app.TextArea_5.Value = '';
%                 app.DropDown_2.Value = '';
%                 app.DropDown_3.Value = '';
%                 app.ButtonGroup.SelectedObject = [];
            else
                % 如果有输入过该智能体的信息，则在接下来所有格子处显示信息
                app.Lamp.Color = 'green';  % 显示该智能体信息已经输入完成
                app.CheckBox.Value = app.Carriers{Current_Number,1}{1,2};
                if strcmp(app.TextArea_2.Enable, 'on')
                    app.TextArea_2.Value = num2str(app.Carriers{Current_Number,2}{1,2});
                end
                app.TextArea_3.Value = num2str(app.Carriers{Current_Number,3}{1,2});
                app.TextArea_4.Value = num2str(app.Carriers{Current_Number,4}{1,2});
                app.DropDown_2.Value = '1';  % 默认选择第一个
                A = app.Carriers{Current_Number,5}{1,2}(1,2);
                switch A
                    case 0
                        app.ButtonGroup.SelectedObject = app.Button;
                    case 1
                        app.ButtonGroup.SelectedObject = app.Button_2;
                    case 3
                        app.ButtonGroup.SelectedObject = app.Button_3;
                end
                B = app.Carriers{Current_Number,5}{1,2}(1,4);
                if B == 0
                    B = Current_Number;
                end
                app.DropDown_3.Value = num2str(B);
                app.TextArea_5.Value = num2str(app.Carriers{Current_Number,5}{1,2}(1,3));
            end    
        end

        % Value changed function: CheckBox
        function Get_If_FC(app, event)
            Current_Number = round(str2double(app.DropDown.Value));
            Flag = 0;
            if isempty(app.Carriers{Current_Number,1})
                Flag = 1;
            elseif app.Carriers{Current_Number,1}{1,2} ~= app.CheckBox.Value
                Flag = 2;
            end
            if Flag ~= 0
                temp1 = cell(1,2);
                temp1{1,1} = "是否是运算中心？ 1是0否";
                value = app.CheckBox.Value;
                temp1{1,2} = value;
                Current_Number = round(str2double(app.DropDown.Value));
                if value  % 如果被勾选
                    app.TextArea_2.Enable = 'on';  % 启用文本框
                else  % 如果未被勾选
                    app.TextArea_2.Enable = 'off';  % 禁用文本框
                end
                app.Carriers{Current_Number,1} = temp1;
                app.Carriers{Current_Number,2} = {};
                app.Carriers{Current_Number,3} = {};
                app.Carriers{Current_Number,4} = {};
                app.Carriers{Current_Number,5} = {};
                app.Lamp.Color = 'red';
                app.Lamp_2.Color = 'red';
                if strcmp(app.TextArea_2.Enable, 'on')
                    app.TextArea_2.Value = '';
                end
                app.TextArea_3.Value = '';
                app.TextArea_4.Value = '';
                app.TextArea_5.Value = '';
                app.Flags_of_Input(Current_Number) = NaN;
            else 
                app.Lamp.Color = 'green';
                if strcmp(app.TextArea_2.Enable, 'on')
                    app.TextArea_2.Value = num2str(app.Carriers{Current_Number,2}{1,2});
                end
                app.TextArea_3.Value = num2str(app.Carriers{Current_Number,3}{1,2});
                app.TextArea_4.Value = num2str(app.Carriers{Current_Number,4}{1,2});
                app.DropDown_2.Value = '1';  % 默认选择第一个
                A = app.Carriers{Current_Number,5}{1,2}(1,2);
                switch A
                    case 0
                        app.ButtonGroup.SelectedObject = app.Button;
                    case 1
                        app.ButtonGroup.SelectedObject = app.Button_2;
                    case 3
                        app.ButtonGroup.SelectedObject = app.Button_3;
                end
                B = app.Carriers{Current_Number,5}{1,2}(1,4);
                if B == 0
                    B = Current_Number;
                end
                app.DropDown_3.Value = num2str(B);
                app.TextArea_5.Value = num2str(app.Carriers{Current_Number,5}{1,2}(1,3));
            end
        end

        % Value changed function: TextArea_2
        function Get_Computing_Capacity(app, event)
            Current_Number = round(str2double(app.DropDown.Value));
            if strcmp(app.TextArea_2.Enable, 'on')
                Computing_Capacity = round(str2double(app.TextArea_2.Value));  % 如果文本框被启用，将Computing_Capacity设置为文本框当前的数值
            else
                Computing_Capacity = 0;  % 如果文本框被禁用，将Computing_Capacity设置为0
            end
            temp1 = cell(1,2);
            temp1{1,1} = "它的最大算力";
            temp1{1,2} = Computing_Capacity;
            app.Carriers{Current_Number,2} = temp1;
        end

        % Value changed function: TextArea_3
        function Get_Communicating_Capacity(app, event)
            Current_Number = round(str2double(app.DropDown.Value));            
            Communicating_Capacity = round(str2double(app.TextArea_3.Value));
            temp1 = cell(1,2);
            temp1{1,1} = "它最大的通讯能力";
            temp1{1,2} = Communicating_Capacity;
            app.Carriers{Current_Number,3} = temp1;
        end

        % Value changed function: TextArea_4
        function Get_Num_of_Sensors(app, event)
            Current_Number = round(str2double(app.DropDown.Value));
            Num_of_Sensors = round(str2double(app.TextArea_4.Value));
            if isempty(app.Carriers{Current_Number,5})
                % 如果还没输入
                app.Lamp.Color = 'red';
                app.Lamp_2.Color = 'red';
                app.DropDown_2.Items = string(1:Num_of_Sensors);  % 创建1到temp的选项
                %app.DropDown_2.Value = '1';  % 默认选择第一个
                app.Sensor_Info_One_Agent = zeros(Num_of_Sensors,4);
                temp1 = cell(1,2);
                temp1{1,1} = "它装载有几个传感器";
                temp1{1,2} = Num_of_Sensors;
                app.Carriers{Current_Number,4} = temp1;
                app.Flags_of_Input(Current_Number) = NaN;
            elseif Num_of_Sensors ~= size(app.Carriers{Current_Number,5},1)
                % 如果更换了传感器数量，则下面的全部清空
                app.DropDown_2.Value = '';
                app.DropDown_3.Value = '';
                app.Lamp.Color = 'red';
                app.Lamp_2.Color = 'red';
                app.DropDown_2.Items = string(1:Num_of_Sensors);  % 创建1到temp的选项
                %app.DropDown_2.Value = '1';  % 默认选择第一个
                app.Sensor_Info_One_Agent = zeros(Num_of_Sensors,4);
                temp1 = cell(1,2);
                temp1{1,1} = "它装载有几个传感器";
                temp1{1,2} = Num_of_Sensors;
                app.Carriers{Current_Number,4} = temp1;
                app.Carriers{Current_Number,5} = {};
                app.TextArea_5.Value = '';
                app.Flags_of_Input(Current_Number) = NaN;
            else
                % 如果已经输入过了
                app.Lamp.Color = 'green';
                app.DropDown_2.Value = '1';  % 默认选择第一个
                A = app.Carriers{Current_Number,5}{1,2}(1,2);
                switch A
                    case 0
                        app.ButtonGroup.SelectedObject = app.Button;
                    case 1
                        app.ButtonGroup.SelectedObject = app.Button_2;
                    case 3
                        app.ButtonGroup.SelectedObject = app.Button_3;
                end
                B = app.Carriers{Current_Number,5}{1,2}(1,4);
                if B == 0
                    B = Current_Number;
                end
                app.DropDown_3.Value = num2str(B);
                app.TextArea_5.Value = num2str(app.Carriers{Current_Number,5}{1,2}(1,3));
            end
        end

        % Value changed function: DropDown_2
        function Get_Current_Number_of_Sensors(app, event)
            Current_Number = round(str2double(app.DropDown.Value));
            Current_Number_of_Sensors = round(str2double(app.DropDown_2.Value));  % 获取当前选中的下拉选项
            Flag = 0;
            if isempty(app.Carriers{Current_Number,5})
                Flag = 1;
            elseif all(app.Carriers{Current_Number,5}{1,2}(Current_Number_of_Sensors,:)==0)
                Flag = 2;
            end
            if Flag ~= 0
                % 如果没有输入过该传感器的信息，则清空接下来所有格子
                app.Lamp.Color = 'red';
                app.Lamp_2.Color = 'red';
                app.TextArea_5.Value = '';
%                 app.DropDown_3.Value = '';
%                 app.ButtonGroup.SelectedObject = [];
                app.Flags_of_Input(Current_Number) = NaN;
            else
                % 如果有输入过该传感器的信息，则在接下来所有格子处显示信息
                app.Lamp.Color = 'green';
                A = app.Carriers{Current_Number,5}{1,2}(Current_Number_of_Sensors,2);
                switch A
                    case 0
                        app.ButtonGroup.SelectedObject = app.Button;
                    case 1
                        app.ButtonGroup.SelectedObject = app.Button_2;
                    case 3
                        app.ButtonGroup.SelectedObject = app.Button_3;
                end
                B = app.Carriers{Current_Number,5}{1,2}(1,4);
                if B == 0
                    B = Current_Number;
                end
                app.DropDown_3.Value = num2str(B);
                app.TextArea_5.Value = num2str(app.Carriers{Current_Number,5}{1,2}(Current_Number_of_Sensors,3));
            end   
        end

        % Callback function: ButtonGroup, DropDown_3
        function Get_Type_of_Sensor(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;  % 获取选中的按钮对象
            Current_Number = round(str2double(app.DropDown.Value));
            Current_Sensor_Number = round(str2double(app.DropDown_2.Value));
            switch selectedButton.Tag
                case '0'
                    app.Sensor_Info_One_Agent(Current_Sensor_Number,1) = 0;
                    app.Sensor_Info_One_Agent(Current_Sensor_Number,2) = 0;
                    app.DropDown_3.Items = string(Current_Number);  % 创建1到temp的选项
                    app.DropDown_3.Value = string(Current_Number);  % 默认选择第一个
                case '1'
                    app.Sensor_Info_One_Agent(Current_Sensor_Number,1) = 1;
                    app.Sensor_Info_One_Agent(Current_Sensor_Number,2) = 1;
                    if Current_Number==1
                        app.DropDown_3.Items = string(2:app.Num_of_Agent);  % 创建2到temp的选项
                        app.DropDown_3.Value = '2';  % 默认选择第一个
                    elseif Current_Number==app.Num_of_Agent
                        app.DropDown_3.Items = string(1:app.Num_of_Agent-1);  % 创建1到temp的选项
                        app.DropDown_3.Value = '1';  % 默认选择第一个
                    else
                        app.DropDown_3.Items = string([1:Current_Number-1,Current_Number+1:app.Num_of_Agent]);  % 创建1到temp的选项
                        app.DropDown_3.Value = '1';  % 默认选择第一个
                    end
                case '3'
                    app.Sensor_Info_One_Agent(Current_Sensor_Number,1) = 1;
                    app.Sensor_Info_One_Agent(Current_Sensor_Number,2) = 3;
                    if Current_Number==1
                        app.DropDown_3.Items = string(2:app.Num_of_Agent);  % 创建2到temp的选项
                        app.DropDown_3.Value = '2';  % 默认选择第一个
                    elseif Current_Number==app.Num_of_Agent
                        app.DropDown_3.Items = string(1:app.Num_of_Agent-1);  % 创建1到temp的选项
                        app.DropDown_3.Value = '1';  % 默认选择第一个
                    else
                        app.DropDown_3.Items = string([1:Current_Number-1,Current_Number+1:app.Num_of_Agent]);  % 创建1到temp的选项
                        app.DropDown_3.Value = '1';  % 默认选择第一个
                    end
            end
        end

        % Value changed function: DropDown_3
        function Get_Object_of_Sensor(app, event)
            Current_Sensor_Number = round(str2double(app.DropDown_2.Value));
            Current_Number = round(str2double(app.DropDown.Value));
            Object = round(str2double(app.DropDown_3.Value));  % 获取当前选中的下拉选项
            if Object == Current_Number
                Object = 0;
            end
            app.Sensor_Info_One_Agent(Current_Sensor_Number,4) = Object;
        end

        % Value changed function: TextArea_5
        function Get_Sensor_Sigma_Square(app, event)
            Sigma_Square = str2double(app.TextArea_5.Value);
            Current_Sensor_Number = round(str2double(app.DropDown_2.Value));
            app.Sensor_Info_One_Agent(Current_Sensor_Number,3) = Sigma_Square;
            Current_Number = round(str2double(app.DropDown.Value));

            % 储存时把显示的部分都存一遍
            Object = round(str2double(app.DropDown_3.Value));  % 获取当前选中的下拉选项
            if Object == Current_Number
                Object = 0;
            end
            app.Sensor_Info_One_Agent(Current_Sensor_Number,4) = Object;
            selectedButton = app.ButtonGroup.SelectedObject;  % 获取选中的按钮对象
            Current_Sensor_Type = selectedButton.Tag;
            app.Sensor_Info_One_Agent(Current_Sensor_Number,2) = round(str2double(Current_Sensor_Type));
            if app.Sensor_Info_One_Agent(Current_Sensor_Number,2) == 0
                app.Sensor_Info_One_Agent(Current_Sensor_Number,1) = 0;
                app.Sensor_Info_One_Agent(Current_Sensor_Number,4) = 0;
            else
                app.Sensor_Info_One_Agent(Current_Sensor_Number,1) = 1;
            end
            Get_Communicating_Capacity(app, event);
            Get_Computing_Capacity(app, event);
            temp1 = cell(1,2);
            temp1{1,1} = "是否是运算中心？ 1是0否";
            value = app.CheckBox.Value;
            temp1{1,2} = value;
            app.Carriers{Current_Number,1} = temp1;
            Num_of_Sensors = round(str2double(app.TextArea_4.Value));
            temp1{1,1} = "它装载有几个传感器";
            temp1{1,2} = Num_of_Sensors;
            app.Carriers{Current_Number,4} = temp1;
        end

        % Button pushed function: Button_4
        function Check_and_Input_One_Agent(app, event)
            Current_Number = round(str2double(app.DropDown.Value));
            % 返回值 result 为是否存在全零行的标志，存在则为 0，否则为 1
            matrix = app.Sensor_Info_One_Agent;
            numRows = size(matrix, 1);
            result = 1;
            for i = 1:numRows
                if all(matrix(i, :) == 0)
                    result = 0;
                    break;
                end
            end
            if result
                app.Lamp.Color = 'green';  % 如果函数返回1，则设置信号灯为绿色
                temp1 = cell(1,2);
                temp1{1,1} = "它几个传感器的类型[1相对0绝对,0绝对1一维3三维,协方差,观测的载体编号(0代表不是相对观测)]";
                temp1{1,2} = app.Sensor_Info_One_Agent;
                app.Sensor_Info_One_Agent = [];
                app.Carriers{Current_Number,5} = temp1;
                app.Flags_of_Input(Current_Number) = 1;
            else
                app.Lamp.Color = 'red';  % 如果函数返回0，则设置信号灯为红色
                app.Lamp_2.Color = 'red';
            end
        end

        % Button pushed function: Button_5
        function Check_and_Input_All_Agent(app, event)
            Num = app.Num_of_Agent;
            array = app.Flags_of_Input;
            if any(isnan(array(1:Num)))
                % 存在 NaN，返回 0
                result = 0;
            else
                % 不存在 NaN，返回 1
                result = 1;
            end
            if result
                app.Lamp_2.Color = 'green';  % 如果函数返回1，则设置信号灯为绿色
                app.Lamp_3.Color = 'green';
%                 privatePropertyValue = app.getPrivateProperty();
%                 assignin('base', 'privatePropertyValue', privatePropertyValue);
            else
                app.Lamp_2.Color = 'red';  % 如果函数返回1，则设置信号灯为绿色
            end
        end

        % Value changed function: DropDown_4
        function Get_P_or_X(app, event)
            value = app.DropDown_4.Value;
            index = find(strcmp(app.Names, value));
            if index==1
                app.UITable.Data = app.P;
            elseif index==2
                app.UITable.Data = app.X;
            else
                app.UITable.Data = app.P_All{1,index-2};
            end
        end

        % Button pushed function: Button_6
        function Save_P_or_X(app, event)
            value = app.DropDown_4.Value;
            index = find(strcmp(app.Names, value));
            if index==1
                app.P = app.UITable.Data;
            elseif index==2
                app.X = app.UITable.Data;
            else
                app.P_All{1,index-2} = app.UITable.Data;
            end
        end

        % Button pushed function: Button_8
        function Save_S_Final(app, event)
            C1 = zeros(1,app.Num_of_Agent);
            C2 = zeros(1,app.Num_of_Agent);
            P_ = NaN(app.Num_of_Agent*3,app.Num_of_Agent*3);
            P_All_ = cell(1,app.Num_of_Agent);
            for i = 1:app.Num_of_Agent
                P_All_{1,i} = NaN(app.Num_of_Agent*3,app.Num_of_Agent*3);
            end
            for i = 1:app.Num_of_Agent
                for j = 1:3
                    P_((i-1)*3+j,(i-1)*3+j) = (app.P(i,i))/3;
                end
                for k = 1:app.Num_of_Agent
                    for j = 1:3
                        P_All_{1,i}((k-1)*3+j,(k-1)*3+j) = (app.P_All{1,i}(k,k))/3;
                    end
                end
            end
            P__ = Sort_Out_Best_from_all_P_Trace(P_All_);
            Flag = Check_The_Input_P(P_,P__);
            if Flag
                app.Lamp_4.Color = 'green';
                app.S_Final = cell(1,6);
                app.S_Final{1,1} = P_;
                app.S_Final{1,2} = app.X;
                for i = 1:app.Num_of_Agent
                    C1(1,i) = app.Carriers{i,2}{1,2};
                    C2(1,i) = app.Carriers{i,3}{1,2};
                end
                app.S_Final{1,3}{1,1} = C1;
                app.S_Final{1,3}{1,2} = C2;
                app.S_Final{1,4} = {};
                app.S_Final{1,5} = {};
                app.S_Final{1,6} = P_All_;
%                 privatePropertyValue = app.getS_Final();
%                 assignin('base', 'S_Final', privatePropertyValue);
                app.Button_10.Enable = "on";
            else
                app.Lamp_4.Color = 'red';
            end
        end

        % Value changed function: CheckBox_2
        function Use_Exist_Data(app, event)
            If_Use_Exist_Data = app.CheckBox_2.Value;
            app.Button_10.Enable = "off";
            app.Carriers = [];
            app.Num_of_Agent = [];
            app.Flags_of_Input = [];
            app.Sensor_Info_One_Agent = [];
            app.P = [];
            app.P_All = [];
            app.X = [];
            app.Names = [];
            app.S_Final = [];
            if isempty(gcp('nocreate'))
                % parpool('local',14);
                app.Lamp_7.Color = 'red';
            end
            if If_Use_Exist_Data
                app.Lamp_5.Color = 'green';
                app.Lamp_4.Color = 'red';
                app.Lamp_3.Color = 'red';
                app.Lamp_2.Color = 'red';
                app.Lamp.Color = 'red';
                app.Button_4.Enable = "off";
                app.Button_5.Enable = "off";
                app.Button_6.Enable = "off";
                app.Button_8.Enable = "off";
                app.TextArea_6.Enable = "on";
                app.TextArea_6.Value = 'Some_Examples\Example2';
                app.Button_9.Enable = "on";
            else
                app.Lamp_5.Color = 'red';
                app.Button_4.Enable = "on";
                app.Button_5.Enable = "on";
                app.Button_6.Enable = "on";
                app.Button_8.Enable = "on";
                app.TextArea_6.Enable = "off";
                app.Button_9.Enable = "off";
            end
        end

        % Button pushed function: Button_9
        function Get_From_Fold(app, event)
            FilePath = app.TextArea_6.Value;
            Path1 = strcat(FilePath,'\Carriers_.mat');
            Path2 = strcat(FilePath,'\Carriers_Position_.mat');
            Path3 = strcat(FilePath,'\S_Final_.mat');
            Path4 = strcat(FilePath,'\Satellite_Position_.mat');
            A = load(Path1{1});
            app.Carriers = A.Carriers;
%             A = load(Path2);
%             app.Carriers_Position = A.Carriers_Position;
            A = load(Path3{1});
            app.S_Final = A.S_Final;
%             A = load(Path4);
%             app.Satellite_Position = A.Satellite_Position;
%             privatePropertyValue = app.getS_Final();
%             assignin('base', 'S_Final', privatePropertyValue);
%             privatePropertyValue = app.getPrivateProperty();
%             assignin('base', 'privatePropertyValue', privatePropertyValue);
            app.Lamp_6.Color = 'green';
            app.Button_10.Enable = "on";
        end

        % Button pushed function: Button_10
        function Start(app, event)
            global Num_Of_Satellite;
            Num_Of_Satellite = 13;
            global Base;
            Base = 8;
            global Carriers_Position;
            global Satellite_Position;
            [Carriers_Position,Satellite_Position] = Environment_(app.Carriers);
            start(app.Carriers,Carriers_Position,app.S_Final,Satellite_Position,app);
        end

        % Value changed function: CheckBox_3
        function IF_Para(app, event)
            value = app.CheckBox_3.Value;
            if value
                if isempty(gcp('nocreate'))
                    parpool('local',14);
                end
                app.Lamp_7.Color = 'green';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {211, '1x', 231};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @Get_Num_of_Agent, true);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '集群中载体个数';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @Get_Current_Number_of_Agent, true);
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '现在输入第';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '个载体的信息';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '是否是运算中心？';
            app.CheckBox = uicheckbox(app.LeftPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @Get_If_FC, true);
            app.CheckBox.Text = '';
            app.TextArea_2 = uitextarea(app.LeftPanel);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @Get_Computing_Capacity, true);
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '请输入它的最大算力';
            app.TextArea_3 = uitextarea(app.LeftPanel);
            app.TextArea_3.ValueChangedFcn = createCallbackFcn(app, @Get_Communicating_Capacity, true);
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '请输入它的最大通信能力';
            app.TextArea_4 = uitextarea(app.LeftPanel);
            app.TextArea_4.ValueChangedFcn = createCallbackFcn(app, @Get_Num_of_Sensors, true);
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '请输入它传感器个数';
            app.DropDown_2 = uidropdown(app.LeftPanel);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @Get_Current_Number_of_Sensors, true);
            app.Label_8 = uilabel(app.LeftPanel);
            app.Label_8.Text = '现在输入第';
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = '个传感器的信息';
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Get_Type_of_Sensor, true);
            app.ButtonGroup.Title = '传感器类型';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Tag = '0';
            app.Button.Text = '绝对观测';
            app.Button.Value = true;
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Tag = '1';
            app.Button_3.Text = '一维相对观测';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Tag = '3';
            app.Button_2.Text = '三维相对观测';
            app.Label_10 = uilabel(app.LeftPanel);
            app.Label_10.Text = '传感器类型选择';
            app.Label_11 = uilabel(app.LeftPanel);
            app.Label_11.Text = '该传感器观测哪个载体';
            app.DropDown_3 = uidropdown(app.LeftPanel);
            app.DropDown_3.DropDownOpeningFcn = createCallbackFcn(app, @Get_Type_of_Sensor, true);
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @Get_Object_of_Sensor, true);
            app.TextArea_5 = uitextarea(app.LeftPanel);
            app.TextArea_5.ValueChangedFcn = createCallbackFcn(app, @Get_Sensor_Sigma_Square, true);
            app.Label_12 = uilabel(app.LeftPanel);
            app.Label_12.Text = '该传感器误差协方差';
            app.Lamp = uilamp(app.LeftPanel);
            app.Button_4 = uibutton(app.LeftPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Check_and_Input_One_Agent, true);
            app.Button_4.Text = '单个载体输入完成';
            app.Lamp_2 = uilamp(app.LeftPanel);
            app.Button_5 = uibutton(app.LeftPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Check_and_Input_All_Agent, true);
            app.Button_5.Text = '所有载体输入完成';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TextArea_7 = uitextarea(app.CenterPanel);
            app.TextArea_7.Editable = 'off';
            app.Panel = uipanel(app.CenterPanel);
            app.Panel.Title = '结果图像';
            app.GridLayout3 = uigridlayout(app.Panel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Lamp_3 = uilamp(app.RightPanel);
            app.Label_13 = uilabel(app.RightPanel);
            app.Label_13.Text = '选择输入内容';
            app.DropDown_4 = uidropdown(app.RightPanel);
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @Get_P_or_X, true);
            app.Button_6 = uibutton(app.RightPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Save_P_or_X, true);
            app.Button_6.Text = '修改';
            app.Button_8 = uibutton(app.RightPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Save_S_Final, true);
            app.Button_8.Text = '确认';
            app.Lamp_4 = uilamp(app.RightPanel);
            app.CheckBox_2 = uicheckbox(app.RightPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @Use_Exist_Data, true);
            app.CheckBox_2.Text = '';
            app.Label_14 = uilabel(app.RightPanel);
            app.Label_14.Text = '是否采用已有数据';
            app.Lamp_5 = uilamp(app.RightPanel);
            app.Label_15 = uilabel(app.RightPanel);
            app.Label_15.Text = '文件夹路径';
            app.TextArea_6 = uitextarea(app.RightPanel);
            app.Button_9 = uibutton(app.RightPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Get_From_Fold, true);
            app.Button_9.Text = '导入';
            app.Lamp_6 = uilamp(app.RightPanel);
            app.Button_10 = uibutton(app.RightPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.Button_10.Text = '开始优化';
            app.CheckBox_3 = uicheckbox(app.RightPanel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @IF_Para, true);
            app.CheckBox_3.Text = '';
            app.Label_16 = uilabel(app.RightPanel);
            app.Label_16.Text = '是否开启并行加速';
            app.Lamp_7 = uilamp(app.RightPanel);
            app.Button_7 = uibutton(app.UIFigure, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end